classdef LAB05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Toolbar        matlab.ui.container.Toolbar
        SaveButton     matlab.ui.container.toolbar.PushTool
        OpenButton     matlab.ui.container.toolbar.PushTool
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        myAxe          matlab.ui.control.UIAxes
        RightPanel     matlab.ui.container.Panel
        data2Edit      matlab.ui.control.EditField
        Data2Label     matlab.ui.control.Label
        FunctionLabel  matlab.ui.control.Label
        Data1Label     matlab.ui.control.Label
        functionEdit   matlab.ui.control.EditField
        drawButton     matlab.ui.control.Button
        dataEdit       matlab.ui.control.EditField
        ContextMenu    matlab.ui.container.ContextMenu
        Menu           matlab.ui.container.Menu
        Menu2          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: drawButton
        function drawButtonPushed(app, event)
%           EditField : data1
%           EditField2 : function
%           EditField_2 : data2
% x.^3+2*y.^2-3
% [-20:0.1:20]
            try
                x1 = app.dataEdit.Value;
                x1 = str2num(x1);

                x2 = app.data2Edit.Value;
                x2 = str2num(x2);

                f = app.functionEdit.Value;
                
                if contains(f,'x') && contains(f,'y')
                    fxy = str2func(['@(x,y)' f]);

                    if isempty(x1) && isempty(x2)
                        msgbox('Vui long nhap it nhat mot o du lieu','Error','error');
                    elseif (isempty(x1) && isempty(x2) == false) || (isempty(x2) && isempty(x1) == false)
                        msgbox('Vui long nhap it nhat o du lieu con lai','Error','error');
                    else
                        [x,y] = meshgrid(x1,x2);
                        z = fxy(x,y);
                        mesh(app.myAxe,x,y,z);
                    end
                elseif (contains(f,'x') && contains(f,'y') == false) || (contains(f,'y') && contains(f,'x') == false)  
                    fxy = str2func(['@(x)' f]);

                    if isempty(x1) && isempty(x2)
                        msgbox('Vui long nhap it nhat mot o du lieu','Error','error');
                    elseif isempty(x1) && isempty(x2) == false
                        plot(app.myAxe, x2, fxy(x2))
                    elseif isempty(x2) && isempty(x1) == false
                        plot(app.myAxe, x1, fxy(x1))
                    else
                        msgbox('So luong du lieu nhieu hon so luong bien cua ham','Error','error');
                    end
                else
                    msgbox('Hay nhap function co x hoac y', 'Error', 'error');
                end
            catch error
                switch (error.identifier)
                    case 'MATLAB:mpower:notScalarAndSquareMatrix'
                        msgbox({'Loi nhan ma tran!'; 'De nhan tung phan tu, dung dau "." truoc lenh.'},'Error','error');
                    case 'MATLAB:m_missing_operator'
                        msgbox('Sai dinh dang ham, vui long nhap lai','Error','error');
                    case 'MATLAB:UndefinedFunction'
                        msgbox('Chi nhap ham mot bien','Error','error');
                    otherwise
                        msgbox({error.identifier,error.message},'Error','error');
                end
            end
        end

        % Value changed function: dataEdit
        function dataEditValueChanged(app, event)
            value = app.dataEdit.Value;
            
        end

        % Value changed function: functionEdit
        function functionEditValueChanged(app, event)
            value = app.functionEdit.Value;
            
        end

        % Button down function: myAxe
        function myAxeButtonDown(app, event)
            
        end

        % Value changed function: data2Edit
        function data2EditValueChanged(app, event)
            value = app.data2Edit.Value;
            
        end

        % Value changing function: dataEdit
        function dataEditValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: data2Edit
        function data2EditValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: functionEdit
        function functionEditValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Clicked callback: SaveButton
        function SaveButtonClicked(app, event)
            [fileName, pathName] = uiputfile('../*.jpg','Save figure as...');
            frame = getframe(app.myAxe);
            image = frame2im(frame);
            imwrite(image,[pathName fileName]);
        end

        % Clicked callback: OpenButton
        function OpenButtonClicked(app, event)
            [fileName, pathName] = uigetfile('../*.csv','Open data...');
            data = readmatrix([pathName fileName]);
            a = data(:,1);
            b = data(:,2);
            disp(a);
            disp(b);
            app.dataEdit.Value = [data(:,1)];
            app.data2Edit.Value = [data(:,2)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.SaveButton = uipushtool(app.Toolbar);
            app.SaveButton.ClickedCallback = createCallbackFcn(app, @SaveButtonClicked, true);
            app.OpenButton = uipushtool(app.Toolbar);
            app.OpenButton.ClickedCallback = createCallbackFcn(app, @OpenButtonClicked, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.myAxe = uiaxes(app.LeftPanel);
            title(app.myAxe, 'Title')
            xlabel(app.myAxe, 'X')
            ylabel(app.myAxe, 'Y')
            zlabel(app.myAxe, 'Z')
            app.myAxe.ButtonDownFcn = createCallbackFcn(app, @myAxeButtonDown, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.dataEdit = uieditfield(app.RightPanel, 'text');
            app.dataEdit.ValueChangedFcn = createCallbackFcn(app, @dataEditValueChanged, true);
            app.dataEdit.ValueChangingFcn = createCallbackFcn(app, @dataEditValueChanging, true);
            app.drawButton = uibutton(app.RightPanel, 'push');
            app.drawButton.ButtonPushedFcn = createCallbackFcn(app, @drawButtonPushed, true);
            app.drawButton.Text = 'Draw';
            app.functionEdit = uieditfield(app.RightPanel, 'text');
            app.functionEdit.ValueChangedFcn = createCallbackFcn(app, @functionEditValueChanged, true);
            app.functionEdit.ValueChangingFcn = createCallbackFcn(app, @functionEditValueChanging, true);
            app.Data1Label = uilabel(app.RightPanel);
            app.Data1Label.Text = 'Data1';
            app.FunctionLabel = uilabel(app.RightPanel);
            app.FunctionLabel.Text = 'Function';
            app.Data2Label = uilabel(app.RightPanel);
            app.Data2Label.Text = 'Data2';
            app.data2Edit = uieditfield(app.RightPanel, 'text');
            app.data2Edit.ValueChangedFcn = createCallbackFcn(app, @data2EditValueChanged, true);
            app.data2Edit.ValueChangingFcn = createCallbackFcn(app, @data2EditValueChanging, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.functionEdit.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAB05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end